classdef MatLabProjectFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        PlottingSignalTab               matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        SignalGenerationTab_2           matlab.ui.container.Tab
        StatusEditField                 matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        SettingsignalsforconvolutionPanel  matlab.ui.container.Panel
        FilterInformationsTextArea_2    matlab.ui.control.TextArea
        OperationSignal2Button          matlab.ui.control.Button
        OperationSignalButton           matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        StopButton                      matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        FilterOptionsPanel              matlab.ui.container.Panel
        FilterInformationsTextArea      matlab.ui.control.TextArea
        FilterSwitch                    matlab.ui.control.Switch
        FilterSwitchLabel               matlab.ui.control.Label
        AdditionalsparametersforspecialcasesPanel  matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SquareTab                       matlab.ui.container.Tab
        Duty0100EditField               matlab.ui.control.NumericEditField
        Duty0100EditFieldLabel          matlab.ui.control.Label
        SawtoothTab                     matlab.ui.container.Tab
        Xmaxforsawtooth01EditField      matlab.ui.control.NumericEditField
        Xmaxforsawtooth01EditFieldLabel  matlab.ui.control.Label
        WhiteNoiseTab                   matlab.ui.container.Tab
        PowerinydBEditField             matlab.ui.control.NumericEditField
        PowerinydBEditFieldLabel        matlab.ui.control.Label
        ChirpTab                        matlab.ui.container.Tab
        SecondFreqencyHzEditField       matlab.ui.control.NumericEditField
        InitialFrequencyHzEditField     matlab.ui.control.NumericEditField
        SecondFreqencyHzEditFieldLabel  matlab.ui.control.Label
        InitialFrequencyHzEditFieldLabel  matlab.ui.control.Label
        TimeofoccurrencesecondfrequencysEditField  matlab.ui.control.NumericEditField
        TimeofoccurrencesecondfrequencysEditFieldLabel  matlab.ui.control.Label
        ParametersOptionsPanel          matlab.ui.container.Panel
        EndtimesEditField               matlab.ui.control.NumericEditField
        EndtimesEditFieldLabel          matlab.ui.control.Label
        StarttimesEditField             matlab.ui.control.NumericEditField
        StarttimesEditFieldLabel        matlab.ui.control.Label
        OperationTypeButtonGroup        matlab.ui.container.ButtonGroup
        SignalGraphandSoundButton       matlab.ui.control.RadioButton
        SignalGraphonlyButton           matlab.ui.control.RadioButton
        SignalTypeDropDown              matlab.ui.control.DropDown
        SignalTypeDropDownLabel         matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        SignalImportExportTab           matlab.ui.container.Tab
        SettingSignalsforConvolutionPanel  matlab.ui.container.Panel
        OperationSignal2Button_2        matlab.ui.control.Button
        OperationSignalButton_2         matlab.ui.control.Button
        FilterInformationsTextArea_4    matlab.ui.control.TextArea
        CurrentlyImportedSignalDataPanel  matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        DirectoryEditField              matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        ImportingSignalPanel            matlab.ui.container.Panel
        ExportingSignalsandSavingPlotsPanel  matlab.ui.container.Panel
        SaveStatusEditField             matlab.ui.control.EditField
        SaveStatusEditFieldLabel        matlab.ui.control.Label
        SaveCurrentSignalButton         matlab.ui.control.Button
        SaveCurrentlyDisplayedPlotButton  matlab.ui.control.Button
        LoadingandPlottingSignalsPanel  matlab.ui.container.Panel
        FilterInformationsTextArea_3    matlab.ui.control.TextArea
        StopaudioButton                 matlab.ui.control.Button
        LoadSignalPlotGraphonlyButton   matlab.ui.control.Button
        LoadSignalPlotGraphandSoundButton  matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        SignalProcessingTab             matlab.ui.container.Tab
        SavingCurrentlyDisplayedPlotsConvolutionandReverbPanel  matlab.ui.container.Panel
        SaveReverbAfterOperationButton  matlab.ui.control.Button
        SaveConvolutionAfterOperationButton  matlab.ui.control.Button
        SaveCurrentPlotAfterOperationButton  matlab.ui.control.Button
        ReverbImportedSignalsOnlyPanel  matlab.ui.container.Panel
        StopAudioButton_2               matlab.ui.control.Button
        PlayReverbAudioButton           matlab.ui.control.Button
        StatusReverbEditField           matlab.ui.control.EditField
        StatusReverbEditFieldLabel      matlab.ui.control.Label
        ReverbButton                    matlab.ui.control.Button
        ConvolutionPanel                matlab.ui.container.Panel
        StopAudioButton                 matlab.ui.control.Button
        PlayConvolutionAudioButton      matlab.ui.control.Button
        StatusConvolutionEditField      matlab.ui.control.EditField
        StatusConvolutionEditFieldLabel  matlab.ui.control.Label
        ConvolutionButton               matlab.ui.control.Button
        FFTFastFourierTransformPanel    matlab.ui.container.Panel
        StatusFFTEditField              matlab.ui.control.EditField
        StatusFFTEditFieldLabel         matlab.ui.control.Label
        FFTButton                       matlab.ui.control.Button
        SignalGraphPanel                matlab.ui.container.Panel
        SignalStatusEditField           matlab.ui.control.EditField
        SignalStatusEditFieldLabel      matlab.ui.control.Label
        OperationSignal2EditField       matlab.ui.control.EditField
        OperationSignal2EditFieldLabel  matlab.ui.control.Label
        OperationSignalEditField        matlab.ui.control.EditField
        OperationSignalEditFieldLabel   matlab.ui.control.Label
        CurrentSignalEditField          matlab.ui.control.EditField
        CurrentSignalEditFieldLabel     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Wszelkie properties dodane w rozwinięciu projektu w pomocy do lepszego operowania na zmiennych
    properties (Access = private)
        filename;
        signal;
        filesave;
        generatedsignal;
        t;
        loadedsignal;
        loadedtime;
        operationsignal;
        operationsignal2;
        info;
        reverbsignal;
        convolutionsignal;
        signaltype;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunSignalIteration(app, event)
            % Cześć z miniprojektu (Początek) [główna część to miniprojekt, dodane elementy w rozwinięciu oznaczone '*' i opisane
            % Tutaj następuje pobranie z EditField's potrzebnych parametrów do generowania konkretnych sygnałów.
            % *dodałem EditField wyświetlający status operacji
            app.StatusEditField.Value = 'Generating ...';
            pause(1);
            A = app.AmplitudeEditField.Value; 
            f = app.FrequencyHzEditField.Value;
            dt = app.EndtimesEditField.Value - app.StarttimesEditField.Value;
            app.t = linspace(app.StarttimesEditField.Value, app.EndtimesEditField.Value, dt*44100);
            dB = app.PowerinydBEditField.Value;
            f0 = app.InitialFrequencyHzEditField.Value;
            t1 = app.TimeofoccurrencesecondfrequencysEditField.Value;
            f1 = app.SecondFreqencyHzEditField.Value;
            % Struktura switch dobrze działa z DropDown'em, kolejne case'y dla kolejnych sygnałów, niezmienna część to "generatedsignal", czyli zmienna dla sygnału i przepis na wygenerowanie go.      
            % Zmienna wychodząc ze switcha przekazywana jest do dalszych operacji.
            % Zmienna "adddata" wykorzystywana jest później do wpisania dodatkowych parametrów w tabelę informacji końcowych dla specjalnych przypadków.
            switch(app.SignalTypeDropDown.Value)
                case 'Sine'
                    app.generatedsignal = A*sin(2*pi*f*app.t);
                    adddata = ' -- ';
                case 'Cosinus'
                    app.generatedsignal = A*cos(2*pi*f*app.t);
                    adddata = ' -- ';
                case 'Square'
                    % "dutysquare" - wypełnienie przebiegu z EditField'a
                    dutysquare = app.Duty0100EditField.Value;
                    app.generatedsignal = A*square(2*pi*f*app.t, dutysquare);
                    adddata = strcat('Duty square :  ', 32, int2str(dutysquare));
                case 'Sawtooth'
                    % "xmax" - maksymalna wartość trójkąta
                    xmax = app.Xmaxforsawtooth01EditField.Value;
                    app.generatedsignal = A*sawtooth(2*pi*f*app.t, xmax);
                    adddata = strcat('Xmax :  ', int2str(xmax));
                case 'Chirp'
                    % f0, f1, t1, kolejne parametry częstotliwości dla chripa
                    app.generatedsignal = A*chirp(app.t, f0, t1, f1);
                    % Ze względu na większą ilość zmiennych "adddata", rozdzielona i "sklejona" z części (32 - korzystam z ASCII, spacja).
                    adddata1 = strcat('Initial Freqency [Hz] :  ', 32, int2str(f0));
                    adddata2 = strcat( 32,'Second Freqency [Hz] :  ', 32, int2str(f1));
                    adddata3 = strcat( 32,'Time of occurrence [s] :  ', 32, int2str(t1));
                    adddata  = strcat(adddata1, adddata2, adddata3);
                case 'White Noise'
                    % W wgn podaje się "Power of noise" w skali logarytmicznej [dBW].
                    whitenoise = wgn(1,44100*dt,dB);
                    app.generatedsignal = whitenoise;
                    adddata = strcat('Power of noise [dBW] :  ', 32, int2str(dB));
                case 'Pink Noise'
                    % Do generowania szumów korzystam z dsp (tworzę ewentualne potrzebne zmienne np. nowe 't' w szumie różowym.).
                    duration = dt;
                    pink = A*10*pinknoise(44100*duration);
                    app.generatedsignal = pink;
                    app.t = linspace(0, duration, duration*44100);
                    adddata = ' -- ';
                case 'Brown Noise'
                    brown = dsp.ColoredNoise('Color', 'brown', 'SamplesPerFrame', dt*44100);
                    x = 0.01*A.*brown();
                    app.generatedsignal = x;
                    adddata = ' -- ';
                case 'Blue Noise'
                    brown = dsp.ColoredNoise('Color', 'blue', 'SamplesPerFrame', dt*44100);
                    x = A/3.*brown();
                    app.generatedsignal = x;
                    adddata = ' -- ';
                case 'Purple Noise'
                    brown = dsp.ColoredNoise('Color', 'purple', 'SamplesPerFrame', dt*44100);
                    x = A/4.*brown();
                    app.generatedsignal = x;
                    adddata = ' -- ';
            end
            % Do tabeli są wpisywane dane o wygenerowanym sygnale (typ, częstotliwość, amplituda, czas trwania i dodatkowe parametry specjalne).
            app.UITable.Data = {app.SignalTypeDropDown.Value app.FrequencyHzEditField.Value app.AmplitudeEditField.Value dt adddata};
            % Switch operujący na "suwaku", decyduje o operacji w zależności czy filtr jest włączony czy wyłączony. Korzysta ze stworzonego w filterBuilder skryptu filtra typu bandpass o zadanych parametrach. Wyświetla, więc albo przefiltrowany
            % albo "czysty" sygnał.
            % *dodałem EditField wyświetlający obecny sygnał i jego status [FFT, Reverb, Signal, Convolution]
            app.signal = app.generatedsignal;
            app.SignalStatusEditField.Value = 'Signal';
            app.CurrentSignalEditField.Value = app.SignalTypeDropDown.Value;
            app.signaltype = app.SignalTypeDropDown.Value;
            switch (app.FilterSwitch.Value)
                case 'Off'
                    plot(app.UIAxes, app.t, app.generatedsignal)
                case 'On'
                    filteredsignal = bandpassfilterproject(app.generatedsignal);
                    plot(app.UIAxes, app.t, filteredsignal);
            end
            % * dodałem skalowanie plot
            app.UIAxes.XLim = [app.StarttimesEditField.Value app.EndtimesEditField.Value];
            % Switch decydujący o ewnetualnym odtworzeniu dźwięku, z dodatkową opcją odtworzenia również przefiltrowanego dźwięku.
            % (korzystam z soundsc z częstotliwością 44100)
            switch (app.OperationTypeButtonGroup.SelectedObject.Text)
            case 'Signal Graph Only'

                case 'Signal Graph and Sound'
                    switch (app.FilterSwitch.Value)
                        case 'Off'
                            soundsc(app.generatedsignal, 44100);
                        case 'On'
                            soundsc(filteredsignal, 44100);
                    end
            end
            % * dodałem zmiany nazewnictwa osi oraz dalsze statusy operacji
            app.UIAxes.XLabel.String = 'Time [s].';
            app.UIAxes.YLabel.String = 'Amplitude';
            app.StatusEditField.Value = 'Done.';
            pause(3);
            app.StatusEditField.Value = 'Ready.';
            % Część z miniprojektu (Koniec)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Przycisk stop służy do zatrzymania odtwatrzania dźwięku w ramach potrzeby (nie trzeba zamykać programu, by wyłączyć odtwarzanie)
            clear sound; % przycisk z miniprojektu
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % * wyszukiwanie pliku do wczytania za pomocą uigetfile oraz czytanie danych o pliku audio i wyświetlenie ich w tabeli (z wyrównaniem do lewej)
            % * struktura try catch dodana w celu zapobnięciu wyświetlenia błedu przy rezygnacji wyboru pliku w oknie wyboru
            try
                [file,path,~] = uigetfile('*.wav');
                app.filename = [path file];
                app.filesave = importdata(app.filename);
                app.DirectoryEditField.Value = app.filename;
                [signaltypeto, Fs] = audioread(app.filename);
                app.info = audioinfo(app.filename);
                duration = app.info.Duration;
                app.loadedtime = linspace(0, duration, duration*Fs);
                app.loadedsignal = signaltypeto;
                Names = {'Filename';'Compression Method';'Number of Channels';'Sample Rate';'Total Samples';'Duration';'Title';'Comment';'Artist';'Bits Per Sample'};
                datatab = horzcat(Names, struct2cell(app.info));
                s = uistyle('HorizontalAlignment', 'left');
                addStyle(app.UITable2, s);
                app.UITable2.Data = datatab;
            catch

            end
        end

        % Button pushed function: LoadSignalPlotGraphonlyButton
        function LoadSignalPlotGraphonlyButtonPushed(app, event)
            % * wczytanie wybranego sygnału i wyświetlenie
            if (isempty(app.loadedsignal) == true)
                fig = uifigure;
                uialert(fig,'Check the file directory.','Invalid File or Missing Directory.');
            else
                plot(app.UIAxes, app.loadedtime, app.loadedsignal);
                app.UIAxes.XLim = [0 getfield(app.info, 'Duration')];
                app.UIAxes.XLabel.String = 'Time [s].';
                app.UIAxes.YLabel.String = 'Amplitude';
                app.CurrentSignalEditField.Value = 'Imported Signal';
                app.SignalStatusEditField.Value = 'Signal';
                app.signal = app.loadedsignal;
                app.signaltype = 'Imported Signal';
            end
        end

        % Button pushed function: LoadSignalPlotGraphandSoundButton
        function LoadSignalPlotGraphandSoundButtonPushed(app, event)
            % * wczytanie wybranego sygnału i wyświetlenie wraz z dźwiękiem
            if (isempty(app.loadedsignal) == true)
                fig = uifigure;
                uialert(fig,'Check the file directory.','Invalid File or Missing Directory.');
            else
                plot(app.UIAxes, app.loadedtime, app.loadedsignal);
                app.UIAxes.XLim = [0 getfield(app.info, 'Duration')];
                app.UIAxes.XLabel.String = 'Time [s].';
                app.UIAxes.YLabel.String = 'Amplitude';
                app.CurrentSignalEditField.Value = 'Imported Signal';
                app.SignalStatusEditField.Value = 'Signal';
                app.signal = app.loadedsignal;
                app.signaltype = 'Imported Signal';
                soundsc(app.loadedsignal, 44100);
            end
        end

        % Button pushed function: StopaudioButton
        function StopaudioButtonPushed(app, event)
            clear sound; % * przycisk do zatrzymania odtwarzania
        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            % * wykonanie FFT na obecnym sygnale i wyświetlenie wraz z komunikatami
            app.StatusFFTEditField.Value = 'Processing';
            pause(1);
            fourierfast = fft(app.signal);
            fs = 44100;
            f = (0:length(fourierfast)-1)*fs/length(fourierfast);
            plot(app.UIAxes, f, abs(fourierfast));
            app.UIAxes.XLim = [0 app.FrequencyHzEditField.Value + 50];
            app.UIAxes.XLabel.String = 'Freqency [Hz].';
            app.UIAxes.YLabel.String = 'Magnitude';
            app.SignalStatusEditField.Value = 'Signal FFT';
            app.StatusFFTEditField.Value = 'Done';
            pause(2);
            app.StatusFFTEditField.Value = 'Ready';
        end

        % Button pushed function: ConvolutionButton
        function ConvolutionButtonPushed(app, event)
            % * wykonanie splotu na dwóch sygnałach wraz z komunikatami w specjalnych przypadkach
            if isempty(app.OperationSignalEditField.Value) == true
                app.StatusConvolutionEditField.Value = 'Operation signal is empty - operation cannot be performed.';
            elseif isempty(app.OperationSignal2EditField.Value) == true
                app.StatusConvolutionEditField.Value = 'Operation signal 2 is empty - operation cannot be performed.';
            else
                app.StatusConvolutionEditField.Value = 'Processing';
                pause(1);
                app.convolutionsignal = conv(app.operationsignal, app.operationsignal2, "full");
                plot(app.UIAxes, app.convolutionsignal);
                app.UIAxes.XLim = [0 length(app.convolutionsignal)];
                app.UIAxes.XLabel.String = 'Time [s].';
                app.UIAxes.YLabel.String = 'Amplitude';
                app.SignalStatusEditField.Value = 'Signals Convolution';
                app.StatusConvolutionEditField.Value = 'Done';
                pause(2);
                app.StatusConvolutionEditField.Value = 'Ready';
            end
        end

        % Button pushed function: OperationSignalButton
        function OperationSignalButtonPushed(app, event)
            % * dodanie obecnego sygnału do późniejszej operacji
            app.operationsignal = app.signal;
            if isempty(app.signal) == false
                app.OperationSignalEditField.Value = app.signaltype;
            end
        end

        % Button pushed function: OperationSignal2Button
        function OperationSignal2ButtonPushed(app, event)
            % * dodanie obecnego sygnału do późniejszej operacji
            app.operationsignal2 = app.signal;
            if isempty(app.signal) == false
                app.OperationSignal2EditField.Value = app.signaltype;
            end
        end

        % Button pushed function: ReverbButton
        function ReverbButtonPushed(app, event)
            % * wykonanie Reverb i wyświetlenie wraz z komunikatami
            app.StatusReverbEditField.Value = 'Processing';
            isimported = strcmp('Imported Signal', app.CurrentSignalEditField.Value);
            if isimported == true
                app.StatusReverbEditField.Value = 'Processing';
                pause(1);
                endtime = getfield(app.info, 'Duration');
                fs = getfield(app.info, 'SampleRate');
                reverb_obj = reverberator('SampleRate', fs);
                app.reverbsignal = reverb_obj(app.signal);
                tk = linspace(0, endtime, fs*endtime);
                plot(app.UIAxes, tk, app.reverbsignal);
                app.UIAxes.XLabel.String = 'Time [s].';
                app.UIAxes.YLabel.String = 'Amplitude';
                app.UIAxes.XLim = [0 endtime];
                app.SignalStatusEditField.Value = 'Signal Reverb';
                app.StatusReverbEditField.Value = 'Done';
                pause(3)
                app.StatusReverbEditField.Value = 'Ready';
            elseif isempty(app.signal) == true
                app.StatusReverbEditField.Value = 'There is no signal to reverb.';
                pause(5);
                app.StatusReverbEditField.Value = 'Ready.';
            else
                app.StatusReverbEditField.Value = 'This operation does not support generated signals.';
                pause(5);
                app.StatusReverbEditField.Value = 'Ready';
            end 
        end

        % Button pushed function: OperationSignalButton_2
        function OperationSignalButton_2Pushed(app, event)
            % * dodanie obecnego sygnału do późniejszej operacji z
            % odpowiednim przekształceniem
            vectorsignal = reshape(app.loadedsignal,[],1);
            app.operationsignal = vectorsignal;
            if isempty(app.loadedsignal) == false
                app.OperationSignalEditField.Value = app.signaltype;
            end
        end

        % Button pushed function: OperationSignal2Button_2
        function OperationSignal2Button_2Pushed(app, event)
            % * dodanie obecnego sygnału do późniejszej operacji z
            % odpowiednim przekształceniem
            vectorsignal = reshape(app.loadedsignal,[],1);
            app.operationsignal2 = vectorsignal;
            if isempty(app.loadedsignal) == false
                app.OperationSignal2EditField.Value = app.signaltype;
            end
        end

        % Button pushed function: SaveCurrentSignalButton
        function SaveCurrentSignalButtonPushed(app, event)
        % * zapisanie obecnego sygnału do wav
        try
            if isempty(app.signal) == false
                [file,~,~] = uiputfile('*.wav');
                audiowrite(file, app.signal, 44100);
                app.SaveStatusEditField.Value = 'Save performed correctly.';
                pause(4);
                app.SaveStatusEditField.Value = 'Ready.';
            else
                app.SaveStatusEditField.Value = 'There is no signal to save.';
                pause(4);
                app.SaveStatusEditField.Value = 'Ready.';
            end
        catch
        
        end
        end

        % Button pushed function: SaveCurrentlyDisplayedPlotButton
        function SaveCurrentlyDisplayedPlotButtonPushed(app, event)
        % * zapisanie plotu do pliku
        try
            filter = {'*.png'; '*.jpg'; '*.pdf'; '*.bmp'};
            [file,~,~] = uiputfile(filter);
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            Axes = app.UIAxes.XAxis.Parent.Children;
            copyobj(Axes, figAxes)
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.ZLim = app.UIAxes.ZLim;
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            saveas(fig, file);
            delete(fig);
        catch

        end
        end

        % Button pushed function: SaveCurrentPlotAfterOperationButton
        function SaveCurrentPlotAfterOperationButtonPushed(app, event)
        % * zapisanie plotu do pliku (drugi przycisk, inna lokalizacja)
        try
            filter = {'*.png'; '*.jpg'; '*.pdf'; '*.bmp'};
            [file,~,~] = uiputfile(filter);
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            Axes = app.UIAxes.XAxis.Parent.Children;
            copyobj(Axes, figAxes)
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.ZLim = app.UIAxes.ZLim;
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            saveas(fig, file);
            delete(fig);
        catch
        
        end
        end

        % Button pushed function: SaveReverbAfterOperationButton
        function SaveReverbAfterOperationButtonPushed(app, event)
         % * zapis konkeretnie reverba, trzymanego w pamięci
        try
            if isempty(app.reverbsignal) == false
                [file,~,~] = uiputfile('*.wav');
                audiowrite(file, app.reverbsignal, 44100);
                app.StatusReverbEditField.Value = 'Save performed correctly.';
                pause(4);
                app.StatusReverbEditField.Value = 'Ready.';
            else
                app.StatusReverbEditField.Value = 'There is no signal to save.';
                pause(4);
                app.StatusReverbEditField.Value = 'Ready.';
            end
        catch

        end
        end

        % Button pushed function: SaveConvolutionAfterOperationButton
        function SaveConvolutionAfterOperationButtonPushed(app, event)
        % * zapis konkeretnie splotu, trzymanego w pamięci
        try
            if isempty(app.convolutionsignal) == false
                [file,~,~] = uiputfile('*.wav');
                audiowrite(file, app.convolutionsignal, 44100);
                app.StatusConvolutionEditField.Value = 'Save performed correctly.';
                pause(4);
                app.StatusConvolutionEditField.Value = 'Ready.';
            else
                app.StatusConvolutionEditField.Value = 'There is no signal to save.';
                pause(4);
                app.StatusConvolutionEditField.Value = 'Ready.';
            end
        catch

        end
        end

        % Button pushed function: PlayReverbAudioButton
        function PlayReverbAudioButtonPushed(app, event)
        % * odtworzenie reverba (również odtwarza ostatni zapamietany)
        if isempty(app.reverbsignal) == false 
            soundsc(app.reverbsignal, 44100);
        else 
            app.StatusReverbEditField.Value = 'There is no reverb in memory.';
            pause(5);
            app.StatusReverbEditField.Value = 'Ready.';
        end
        end

        % Button pushed function: PlayConvolutionAudioButton
        function PlayConvolutionAudioButtonPushed(app, event)
        % * odtworzenie splotu (również odtwarza ostatni zapamietany)
        if isempty(app.convolutionsignal) == false 
            soundsc(app.convolutionsignal, 44100);
        else 
            app.StatusConvolutionEditField.Value = 'There is no convolution in memory.';
            pause(5);
            app.StatusConvolutionEditField.Value = 'Ready.';
        end
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            % * zatrzymanie odtwarzania dźwięku
            clear sound;
        end

        % Button pushed function: StopAudioButton_2
        function StopAudioButton_2Pushed(app, event)
             % * zatrzymanie odtwarzania dźwięku
            clear sound;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SignalGraphPanel = uipanel(app.UIFigure);
            app.SignalGraphPanel.Title = 'Signal Graph';
            app.UIAxes = uiaxes(app.SignalGraphPanel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.CurrentSignalEditFieldLabel = uilabel(app.SignalGraphPanel);
            app.CurrentSignalEditFieldLabel.Text = 'Current Signal';
            app.CurrentSignalEditField = uieditfield(app.SignalGraphPanel, 'text');
            app.OperationSignalEditFieldLabel = uilabel(app.SignalGraphPanel);
            app.OperationSignalEditFieldLabel.Text = 'Operation Signal';
            app.OperationSignalEditField = uieditfield(app.SignalGraphPanel, 'text');
            app.OperationSignal2EditFieldLabel = uilabel(app.SignalGraphPanel);
            app.OperationSignal2EditFieldLabel.Text = 'Operation Signal 2';
            app.OperationSignal2EditField = uieditfield(app.SignalGraphPanel, 'text');
            app.SignalStatusEditFieldLabel = uilabel(app.SignalGraphPanel);
            app.SignalStatusEditFieldLabel.Text = 'Signal Status';
            app.SignalStatusEditField = uieditfield(app.SignalGraphPanel, 'text');
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.PlottingSignalTab = uitab(app.TabGroup2);
            app.PlottingSignalTab.Title = 'Plotting Signal';
            app.TabGroup3 = uitabgroup(app.PlottingSignalTab);
            app.SignalGenerationTab_2 = uitab(app.TabGroup3);
            app.SignalGenerationTab_2.Title = 'Signal Generation';
            app.ParametersOptionsPanel = uipanel(app.SignalGenerationTab_2);
            app.ParametersOptionsPanel.Title = 'Parameters Options';
            app.FrequencyHzEditFieldLabel = uilabel(app.ParametersOptionsPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency [Hz]';
            app.FrequencyHzEditField = uieditfield(app.ParametersOptionsPanel, 'numeric');
            app.FrequencyHzEditField.Limits = [0 Inf];
            app.AmplitudeEditFieldLabel = uilabel(app.ParametersOptionsPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.ParametersOptionsPanel, 'numeric');
            app.SignalTypeDropDownLabel = uilabel(app.ParametersOptionsPanel);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.ParametersOptionsPanel);
            app.SignalTypeDropDown.Items = {'Sine', 'Cosinus', 'Square', 'Sawtooth', 'Chirp', 'White Noise', 'Pink Noise', 'Brown Noise', 'Blue Noise', 'Purple Noise'};
            app.SignalTypeDropDown.Value = 'Sine';
            app.OperationTypeButtonGroup = uibuttongroup(app.ParametersOptionsPanel);
            app.OperationTypeButtonGroup.Title = 'Operation Type';
            app.SignalGraphonlyButton = uiradiobutton(app.OperationTypeButtonGroup);
            app.SignalGraphonlyButton.Text = 'Signal Graph only';
            app.SignalGraphonlyButton.Value = true;
            app.SignalGraphandSoundButton = uiradiobutton(app.OperationTypeButtonGroup);
            app.SignalGraphandSoundButton.Text = 'Signal Graph and Sound';
            app.StarttimesEditFieldLabel = uilabel(app.ParametersOptionsPanel);
            app.StarttimesEditFieldLabel.Text = 'Start time [s]';
            app.StarttimesEditField = uieditfield(app.ParametersOptionsPanel, 'numeric');
            app.StarttimesEditField.Limits = [0 Inf];
            app.EndtimesEditFieldLabel = uilabel(app.ParametersOptionsPanel);
            app.EndtimesEditFieldLabel.Text = 'End time [s]';
            app.EndtimesEditField = uieditfield(app.ParametersOptionsPanel, 'numeric');
            app.EndtimesEditField.Limits = [0.001 Inf];
            app.EndtimesEditField.ValueDisplayFormat = '%.3f';
            app.EndtimesEditField.Value = 0.1;
            app.AdditionalsparametersforspecialcasesPanel = uipanel(app.SignalGenerationTab_2);
            app.AdditionalsparametersforspecialcasesPanel.Title = 'Additionals parameters (for special cases)';
            app.TabGroup = uitabgroup(app.AdditionalsparametersforspecialcasesPanel);
            app.SquareTab = uitab(app.TabGroup);
            app.SquareTab.Title = 'Square';
            app.Duty0100EditFieldLabel = uilabel(app.SquareTab);
            app.Duty0100EditFieldLabel.Text = {'Duty'; '0-100'};
            app.Duty0100EditField = uieditfield(app.SquareTab, 'numeric');
            app.Duty0100EditField.Limits = [0 100];
            app.SawtoothTab = uitab(app.TabGroup);
            app.SawtoothTab.Title = 'Sawtooth';
            app.Xmaxforsawtooth01EditFieldLabel = uilabel(app.SawtoothTab);
            app.Xmaxforsawtooth01EditFieldLabel.Text = {'Xmax (for sawtooth)'; '0-1'};
            app.Xmaxforsawtooth01EditField = uieditfield(app.SawtoothTab, 'numeric');
            app.Xmaxforsawtooth01EditField.Limits = [0 1];
            app.WhiteNoiseTab = uitab(app.TabGroup);
            app.WhiteNoiseTab.Title = 'White Noise';
            app.PowerinydBEditFieldLabel = uilabel(app.WhiteNoiseTab);
            app.PowerinydBEditFieldLabel.Text = 'Power in y (dB)';
            app.PowerinydBEditField = uieditfield(app.WhiteNoiseTab, 'numeric');
            app.ChirpTab = uitab(app.TabGroup);
            app.ChirpTab.Title = 'Chirp';
            app.TimeofoccurrencesecondfrequencysEditFieldLabel = uilabel(app.ChirpTab);
            app.TimeofoccurrencesecondfrequencysEditFieldLabel.Text = {'Time of occurrence '; '(second frequency)'; '[ s ]'};
            app.TimeofoccurrencesecondfrequencysEditField = uieditfield(app.ChirpTab, 'numeric');
            app.TimeofoccurrencesecondfrequencysEditField.Limits = [0.001 Inf];
            app.TimeofoccurrencesecondfrequencysEditField.Value = 0.001;
            app.InitialFrequencyHzEditFieldLabel = uilabel(app.ChirpTab);
            app.InitialFrequencyHzEditFieldLabel.Text = {'Initial Frequency'; '[ Hz ]'};
            app.SecondFreqencyHzEditFieldLabel = uilabel(app.ChirpTab);
            app.SecondFreqencyHzEditFieldLabel.Text = {'Second Freqency'; '[ Hz ]'};
            app.InitialFrequencyHzEditField = uieditfield(app.ChirpTab, 'numeric');
            app.InitialFrequencyHzEditField.Limits = [0 Inf];
            app.SecondFreqencyHzEditField = uieditfield(app.ChirpTab, 'numeric');
            app.SecondFreqencyHzEditField.Limits = [0 Inf];
            app.FilterOptionsPanel = uipanel(app.SignalGenerationTab_2);
            app.FilterOptionsPanel.Title = 'Filter Options';
            app.FilterSwitchLabel = uilabel(app.FilterOptionsPanel);
            app.FilterSwitchLabel.Text = 'Filter Switch';
            app.FilterSwitch = uiswitch(app.FilterOptionsPanel, 'slider');
            app.FilterInformationsTextArea = uitextarea(app.FilterOptionsPanel);
            app.FilterInformationsTextArea.Value = {'Bandpass Filter'; '1023 Hz'; '1726 Hz'};
            app.RunButton = uibutton(app.SignalGenerationTab_2, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunSignalIteration, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.SignalGenerationTab_2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.UITable = uitable(app.SignalGenerationTab_2);
            app.UITable.ColumnName = {'Signal Type'; 'Frequency [Hz]'; 'Amplitude'; 'Signal Duration Time [s]'; 'Additional Data'};
            app.UITable.RowName = {};
            app.SettingsignalsforconvolutionPanel = uipanel(app.SignalGenerationTab_2);
            app.SettingsignalsforconvolutionPanel.Title = 'Setting signals for convolution';
            app.OperationSignalButton = uibutton(app.SettingsignalsforconvolutionPanel, 'push');
            app.OperationSignalButton.ButtonPushedFcn = createCallbackFcn(app, @OperationSignalButtonPushed, true);
            app.OperationSignalButton.Text = 'Operation Signal';
            app.OperationSignal2Button = uibutton(app.SettingsignalsforconvolutionPanel, 'push');
            app.OperationSignal2Button.ButtonPushedFcn = createCallbackFcn(app, @OperationSignal2ButtonPushed, true);
            app.OperationSignal2Button.Text = 'Operation Signal 2';
            app.FilterInformationsTextArea_2 = uitextarea(app.SettingsignalsforconvolutionPanel);
            app.FilterInformationsTextArea_2.Value = {'Here you can set currently displayed signals as "operation signals" for future convolution.'};
            app.StatusEditFieldLabel = uilabel(app.SignalGenerationTab_2);
            app.StatusEditFieldLabel.Text = 'Status ';
            app.StatusEditField = uieditfield(app.SignalGenerationTab_2, 'text');
            app.StatusEditField.Value = 'Ready.';
            app.SignalImportExportTab = uitab(app.TabGroup3);
            app.SignalImportExportTab.Title = 'Signal Import/Export';
            app.ImportingSignalPanel = uipanel(app.SignalImportExportTab);
            app.ImportingSignalPanel.Title = 'Importing Signal';
            app.BrowseButton = uibutton(app.ImportingSignalPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadingandPlottingSignalsPanel = uipanel(app.ImportingSignalPanel);
            app.LoadingandPlottingSignalsPanel.Title = 'Loading and Plotting Signals';
            app.LoadSignalPlotGraphandSoundButton = uibutton(app.LoadingandPlottingSignalsPanel, 'push');
            app.LoadSignalPlotGraphandSoundButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSignalPlotGraphandSoundButtonPushed, true);
            app.LoadSignalPlotGraphandSoundButton.Text = 'Load Signal (Plot Graph and Sound)';
            app.LoadSignalPlotGraphonlyButton = uibutton(app.LoadingandPlottingSignalsPanel, 'push');
            app.LoadSignalPlotGraphonlyButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSignalPlotGraphonlyButtonPushed, true);
            app.LoadSignalPlotGraphonlyButton.Text = 'Load Signal (Plot Graph only)';
            app.StopaudioButton = uibutton(app.LoadingandPlottingSignalsPanel, 'push');
            app.StopaudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopaudioButtonPushed, true);
            app.StopaudioButton.Text = 'Stop audio';
            app.FilterInformationsTextArea_3 = uitextarea(app.LoadingandPlottingSignalsPanel);
            app.FilterInformationsTextArea_3.Value = {'Here you can load your signal after importing it.'};
            app.ExportingSignalsandSavingPlotsPanel = uipanel(app.ImportingSignalPanel);
            app.ExportingSignalsandSavingPlotsPanel.Title = 'Exporting Signals and Saving Plots';
            app.SaveCurrentlyDisplayedPlotButton = uibutton(app.ExportingSignalsandSavingPlotsPanel, 'push');
            app.SaveCurrentlyDisplayedPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentlyDisplayedPlotButtonPushed, true);
            app.SaveCurrentlyDisplayedPlotButton.Text = 'Save Currently Displayed Plot';
            app.SaveCurrentSignalButton = uibutton(app.ExportingSignalsandSavingPlotsPanel, 'push');
            app.SaveCurrentSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentSignalButtonPushed, true);
            app.SaveCurrentSignalButton.Text = 'Save Current Signal';
            app.SaveStatusEditFieldLabel = uilabel(app.ExportingSignalsandSavingPlotsPanel);
            app.SaveStatusEditFieldLabel.Text = 'Save Status';
            app.SaveStatusEditField = uieditfield(app.ExportingSignalsandSavingPlotsPanel, 'text');
            app.SaveStatusEditField.Value = 'Ready.';
            app.DirectoryEditFieldLabel = uilabel(app.SignalImportExportTab);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.SignalImportExportTab, 'text');
            app.CurrentlyImportedSignalDataPanel = uipanel(app.SignalImportExportTab);
            app.CurrentlyImportedSignalDataPanel.Title = 'Currently Imported Signal Data';
            app.UITable2 = uitable(app.CurrentlyImportedSignalDataPanel);
            app.UITable2.ColumnName = {'Data'; 'Value'};
            app.UITable2.RowName = {};
            app.SettingSignalsforConvolutionPanel = uipanel(app.SignalImportExportTab);
            app.SettingSignalsforConvolutionPanel.Title = 'Setting Signals for Convolution';
            app.FilterInformationsTextArea_4 = uitextarea(app.SettingSignalsforConvolutionPanel);
            app.FilterInformationsTextArea_4.Value = {'Here you can set currently displayed signals as "operation signals" for future convolution.'};
            app.OperationSignalButton_2 = uibutton(app.SettingSignalsforConvolutionPanel, 'push');
            app.OperationSignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @OperationSignalButton_2Pushed, true);
            app.OperationSignalButton_2.Text = 'Operation Signal';
            app.OperationSignal2Button_2 = uibutton(app.SettingSignalsforConvolutionPanel, 'push');
            app.OperationSignal2Button_2.ButtonPushedFcn = createCallbackFcn(app, @OperationSignal2Button_2Pushed, true);
            app.OperationSignal2Button_2.Text = 'Operation Signal 2';
            app.SignalProcessingTab = uitab(app.TabGroup2);
            app.SignalProcessingTab.Title = 'Signal Processing';
            app.FFTFastFourierTransformPanel = uipanel(app.SignalProcessingTab);
            app.FFTFastFourierTransformPanel.Title = 'FFT (Fast Fourier Transform)';
            app.FFTButton = uibutton(app.FFTFastFourierTransformPanel, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Text = 'FFT';
            app.StatusFFTEditFieldLabel = uilabel(app.FFTFastFourierTransformPanel);
            app.StatusFFTEditFieldLabel.Text = 'Status FFT';
            app.StatusFFTEditField = uieditfield(app.FFTFastFourierTransformPanel, 'text');
            app.StatusFFTEditField.Value = 'Ready';
            app.ConvolutionPanel = uipanel(app.SignalProcessingTab);
            app.ConvolutionPanel.Title = 'Convolution';
            app.ConvolutionButton = uibutton(app.ConvolutionPanel, 'push');
            app.ConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolutionButtonPushed, true);
            app.ConvolutionButton.Text = 'Convolution';
            app.StatusConvolutionEditFieldLabel = uilabel(app.ConvolutionPanel);
            app.StatusConvolutionEditFieldLabel.Text = 'Status Convolution';
            app.StatusConvolutionEditField = uieditfield(app.ConvolutionPanel, 'text');
            app.StatusConvolutionEditField.Value = 'Ready';
            app.PlayConvolutionAudioButton = uibutton(app.ConvolutionPanel, 'push');
            app.PlayConvolutionAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayConvolutionAudioButtonPushed, true);
            app.PlayConvolutionAudioButton.Text = 'Play Convolution Audio';
            app.StopAudioButton = uibutton(app.ConvolutionPanel, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop Audio';
            app.ReverbImportedSignalsOnlyPanel = uipanel(app.SignalProcessingTab);
            app.ReverbImportedSignalsOnlyPanel.Title = 'Reverb (Imported Signals Only)';
            app.ReverbButton = uibutton(app.ReverbImportedSignalsOnlyPanel, 'push');
            app.ReverbButton.ButtonPushedFcn = createCallbackFcn(app, @ReverbButtonPushed, true);
            app.ReverbButton.Text = 'Reverb';
            app.StatusReverbEditFieldLabel = uilabel(app.ReverbImportedSignalsOnlyPanel);
            app.StatusReverbEditFieldLabel.Text = 'Status Reverb';
            app.StatusReverbEditField = uieditfield(app.ReverbImportedSignalsOnlyPanel, 'text');
            app.StatusReverbEditField.Value = 'Ready';
            app.PlayReverbAudioButton = uibutton(app.ReverbImportedSignalsOnlyPanel, 'push');
            app.PlayReverbAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayReverbAudioButtonPushed, true);
            app.PlayReverbAudioButton.Text = 'Play Reverb Audio';
            app.StopAudioButton_2 = uibutton(app.ReverbImportedSignalsOnlyPanel, 'push');
            app.StopAudioButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButton_2Pushed, true);
            app.StopAudioButton_2.Text = 'Stop Audio';
            app.SavingCurrentlyDisplayedPlotsConvolutionandReverbPanel = uipanel(app.SignalProcessingTab);
            app.SavingCurrentlyDisplayedPlotsConvolutionandReverbPanel.Title = 'Saving Currently Displayed Plots, Convolution and Reverb';
            app.SaveCurrentPlotAfterOperationButton = uibutton(app.SavingCurrentlyDisplayedPlotsConvolutionandReverbPanel, 'push');
            app.SaveCurrentPlotAfterOperationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentPlotAfterOperationButtonPushed, true);
            app.SaveCurrentPlotAfterOperationButton.Text = 'Save Current Plot After Operation';
            app.SaveConvolutionAfterOperationButton = uibutton(app.SavingCurrentlyDisplayedPlotsConvolutionandReverbPanel, 'push');
            app.SaveConvolutionAfterOperationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConvolutionAfterOperationButtonPushed, true);
            app.SaveConvolutionAfterOperationButton.Text = 'Save Convolution After Operation';
            app.SaveReverbAfterOperationButton = uibutton(app.SavingCurrentlyDisplayedPlotsConvolutionandReverbPanel, 'push');
            app.SaveReverbAfterOperationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveReverbAfterOperationButtonPushed, true);
            app.SaveReverbAfterOperationButton.Text = 'Save Reverb After Operation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatLabProjectFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end